classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ThemeDropDown       matlab.ui.control.DropDown
        ThemeDropDownLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % import files when the app starts

            
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            app_theme = app.ThemeDropDown.Value;
            % actions to take based on different values
            switch app_theme
                case "Light"              % Light mode
                    color1 = [0.96, 0.96, 0.96]; % very very very light gray
                    color2 = [0, 0, 0];          % pitch black
                case "Dark"               % Dark mode
                    color1 = [0.13, 0.13, 0.13]; % very dark gray
                    color2 = [0.96, 0.96, 0.96]; % very very very light gray
                case "Midnight"           % 'Dark-er' mode
                    color1 = [0, 0, 0];          % pitch black
                    color2 = [0.2, 0.86, 0.86];  % light cyan (white is too bright, plus it looks cool)
            end
            % Defines attributes needing to = color1 when theme is changed
            color1_targets = {
                app.UIFigure,               'Color';
                app.ThemeDropDown,          'BackgroundColor'};

            % Defines attributes needing to = color2 when theme is changed
            color2_targets = {
                app.ThemeDropDown,          'FontColor';
                app.ThemeDropDownLabel,     'FontColor'};

            % Each attribute in color1_targets is changed to color1
            for i = 1:size(color1_targets,1)
                set(color1_targets{i,1}, color1_targets{i,2}, color1);
            end

            % Each attribute in color2_targets is changed to color2
            for i = 1:size(color2_targets,1)
                set(color2_targets{i,1}, color2_targets{i,2}, color2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThemeDropDownLabel = uilabel(app.UIFigure);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.ThemeDropDown = uidropdown(app.UIFigure);
            app.ThemeDropDown.Items = {'Light', 'Dark', 'Midnight'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Placeholder = 'Theme';
            app.ThemeDropDown.Value = 'Dark';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end